classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                    % UI Figure
        UIAxes                 matlab.ui.control.UIAxes           
        Panel                  matlab.ui.container.Panel           % Controls
        LabelSlider            matlab.ui.control.Label             % Densit...
        DensitySlider          matlab.ui.control.Slider            % [1000 ...
        LabelSlider2           matlab.ui.control.Label             % Diamet...
        DiameterSlider         matlab.ui.control.Slider            % [0 5]
        StartButton            matlab.ui.control.Button            % Drop B...
        Label                  matlab.ui.control.Label             % Simple...
        Label2                 matlab.ui.control.Label             % Comple...
        Label3                 matlab.ui.control.Label             % Differ...
        Button                 matlab.ui.control.Button            % What If?
        StopButton             matlab.ui.control.Button            % Stop
        LabelSemicircularGauge matlab.ui.control.Label             % Simple...
        SimpleVelocity         matlab.ui.control.SemicircularGauge % [0 5]
        Label4                 matlab.ui.control.Label             % Comple...
        ComplexVelocity        matlab.ui.control.SemicircularGauge % [0 5]
    end

    properties (Access = private)
        diameter = .2183
        mass = 7
        stop = 0;
        running = 0;
    end

    methods (Access = private)
        
        function results = seconds2text(app,t)
            hours = int8(floor(t/3600));
            minutes = int8(floor(mod(t/60,60)));
            seconds  = int8(floor(mod(t,60)));
            results = strcat(sprintf('%2d',hours),':',sprintf('%02d',minutes),':',sprintf('%02d',seconds));
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % DiameterSlider value changed function
        function diameter_updated(app)
            app.diameter= max(0.1,app.DiameterSlider.Value);
            r = app.diameter/2;
            vol = 4/3*pi*r^3;
            app.mass = app.DensitySlider.Value*vol;
        end

        % DensitySlider value changed function
        function mass_updated(app)
            r = app.diameter/2;
            vol = 4/3*pi*r^3;
            app.mass = app.DensitySlider.Value*vol;
        end

        % StartButton button pushed function
        function start(app)
            disp('starting');
            ax = app.UIAxes;
            app.stop = 0;
            app.StopButton.Enable = 'on';
            max_depth = 10916;
            t_step = 30;
            diameter = app.diameter;
            [time1, depth1] = DropBall(diameter,app.mass,max_depth,1,1);
            vel1 = depth1(:,2);
            depth1 = depth1(:,1);
            [time2, depth2] = DropBall(diameter,app.mass,max_depth,0,0);
            vel2 = depth2(:,2);
            depth2 = depth2(:,1);
            times = 0:t_step:max(time1(end),time2(end));
                    set(ax,'ydir','reverse','XTick',[]);
                %axis(ax,[0,75,max([d1,d2])-100,max([d1,d2]) + 25]);
                t1set = 0;
                t2set = 0;
                axis(ax,[0,100,0,max_depth]);
            for t = times
                if ~t2set && t+t_step >= time2(end) 
                    t2set = 1;
                    app.Label.Text = strcat('Simple Ball:',seconds2text(app,time2(end)));
                end
                if ~t1set && t+t_step >= time1(end)
                    t1set = 1;
                    app.Label2.Text = strcat('Complex Ball:',seconds2text(app,time1(end)));
                end
                v1 = interp1(time1,vel1,t,'linear',vel1(end));
                v2 = interp1(time2,vel2,t,'linear',vel2(end));
                app.SimpleVelocity.Value = v2;
                app.ComplexVelocity.Value = v1;
                d1 = interp1(time1,depth1,t,'linear',max(depth1));
                d2 = interp1(time2,depth2,t,'linear',max(depth2));
                hold(ax, 'off');
                plot(ax,50,d1,'o','MarkerSize',50*sqrt(diameter),...
            'MarkerEdgeColor','r',...
            'MarkerFaceColor','b');
                hold(ax,'on');
                plot(ax,80,1000,'o','MarkerSize',20,'MarkerEdgeColor','r','MarkerFaceColor','b');
                text(ax,85,1000,'Complex','FontSize',20);
                plot(ax,25,d2,'o','MarkerSize',50*sqrt(diameter),...
            'MarkerEdgeColor','b',...
            'MarkerFaceColor','r');
                 plot(ax,80,500,'o','MarkerSize',20,'MarkerEdgeColor','b','MarkerFaceColor','r');
            text(ax,85,500,'Simple','FontSize',20);
            pause(0.001);
            if app.stop
                disp('stopping');
                break;
            end
            end
            app.StopButton.Enable = 'off';
            disp('stopped runnign');
            app.running = 0;
            app.Label3.Text = strcat('Difference:',seconds2text(app,abs(time1(end)-time2(end))));
        end

        % Button button pushed function
        function whatIf(app)
            app.diameter = .2183;
            app.DensitySlider.Value = 1285;
            app.DiameterSlider.Value = .2183;
            app.mass = 7;
        end

        % StopButton button pushed function
        function stop_sim(app)
            app.stop = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 75];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 10916];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.TickDirMode = 'manual';
            app.UIAxes.XTickLabel = {''; ''};
            app.UIAxes.YTickLabel = {' '};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Controls';
            app.Panel.Units = 'pixels';
            app.LabelSlider = uilabel(app.Panel);
            app.LabelSlider.Text = 'Density (kg/m^3)';
            app.DensitySlider = uislider(app.Panel);
            app.DensitySlider.Limits = [1000 2000];
            app.DensitySlider.ValueChangedFcn = createCallbackFcn(app, @mass_updated);
            app.DensitySlider.Value = 1285;
            app.LabelSlider2 = uilabel(app.Panel);
            app.LabelSlider2.Text = 'Diameter (m)';
            app.DiameterSlider = uislider(app.Panel);
            app.DiameterSlider.Limits = [0 5];
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @diameter_updated);
            app.DiameterSlider.Value = 0.2183;
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start);
            app.StartButton.Text = 'Drop Balls!';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Simple Ball: 0:00:00';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Complex Ball: 0:00:00';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Difference: 0:00:00';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @whatIf);
            app.Button.Text = 'What If?';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stop_sim);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.LabelSemicircularGauge = uilabel(app.Panel);
            app.LabelSemicircularGauge.Text = 'Simple Velocity (m/s)';
            app.SimpleVelocity = uigauge(app.Panel, 'semicircular');
            app.SimpleVelocity.Limits = [0 5];
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Complex Velocity (m/s)';
            app.ComplexVelocity = uigauge(app.Panel, 'semicircular');
            app.ComplexVelocity.Limits = [0 5];
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end